classdef Human_monitoring < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        HumanMonitoringButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
        StartButton            matlab.ui.control.Button
        TextArea               matlab.ui.control.TextArea
        StopButton             matlab.ui.control.Button
        TimeLabel              matlab.ui.control.Label
        displaytime            matlab.ui.control.NumericEditField
    end

     properties (Access = public)
        Property2 % Description
    end


    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
             % Initializing variables and loading data
            global go
            go = true;
%             load('4Gesture_training_testing_denoised.mat');
%             load('TrainedParameters_Civillab_Fall_EmptyRoom_30_April_100percentAccuracy.mat');

            load('stackednet.mat');            
            tic;

            while go

                    %% Initializing Parameters
                    tx_antenna = 1; % the first transmission antenna
                    rx_antenna = 1; % the first receiving antenna
                    %subcarrier = 1; % the index of subcarrier that is observed. The value must be between 1 and 30. 

                    num_packet = 969;
                    num_subcarrier = 30;
                    
                    %window_start = 1; % starting index of the window
                    %window_end = window_length;
                    
                    interval = 200; % moving interval of the window
                    old_end_index = 0;   
                    
                    denoise_level = 5;
                    
                    %% Read CSI Trace from Data File
                    while 1
                        csi_trace = read_bf_file('Krunal_sitting.dat');  % file name is 'demo.dat'
                        [end_file, m] = size(csi_trace);
                       
                        end_index = 0;
                        for i = 1:end_file
                            if ~isempty(csi_trace{i}) 
                               end_index = end_index+1;
                            end
                        end
                    
                        num_instance = floor(((end_index-old_end_index)-num_packet)/interval+1);
                        
                        if num_instance >= 1
                           for k = 1:num_instance
                               instance = zeros(num_subcarrier, num_packet);
                               for i = 1:num_packet  
                                   csi_entry = csi_trace{old_end_index+(k-1)*interval+i}; % extracting 3D matrix from the structure
                                   csi = get_scaled_csi(csi_entry); % converting 3D matrix to 2D
                                   newData = abs(csi(tx_antenna,rx_antenna,1));
                                   instance(:,k) = newData(:); 
                               end
                      
                    
                    %% Plot Data and Denoise
                         
                               
                               for i = 1:num_subcarrier
                                   [C, L] = wavedec(instance(i,:), denoise_level, 'db1'); % wavelet-based denosing
                                   instance(i,:) = wrcoef('a', C, L, 'db1', denoise_level);
                               end
                    
                                  
                    %% Send Data to ML Model
                               instance_denoise = instance(:);
                               [Prob, instance_label] = max(stackednet(instance_denoise));
                                    plot(app.UIAxes,instance_denoise);
                                    app.UIAxes.XLabel.String = 'Time';
                                        if (instance_label == 1)  
                                            app.TextArea.FontColor = 'red';
                                            app.TextArea.Value = 'Fall';
                                            go = false;
                                            break; 
                                     
                                        elseif (instance_label == 2) 
                                            app.TextArea.FontColor = 'black';
                                            app.TextArea.Value = 'Normal';
                                             
                                     
                                        end
                                        
                                        if go == false
                                        break;
                                        end
                                        app.displaytime.Value = toc;
                                        pause(0.1);
                            end
                        end
                               
                                old_end_index = old_end_index+(num_instance-1)*interval+num_packet;
                              
                    end
            end
            

        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            global go
            go = false;
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
            value = app.TextArea.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.HumanMonitoringButton = uibutton(app.UIFigure, 'push');
            app.HumanMonitoringButton.Text = 'Human Monitoring';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Signal Display')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.XLim = [0 998];
            app.UIAxes.YLim = [0 30];
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time';
            app.displaytime = uieditfield(app.UIFigure, 'numeric');
            app.displaytime.ValueDisplayFormat = '%11.3g';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Human_monitoring

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end